classdef MainView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CalculateButton                 matlab.ui.control.Button
        MaxNOTranslationsEditFieldLabel  matlab.ui.control.Label
        MaxNOTranslationsEditField      matlab.ui.control.NumericEditField
        CalibrationSetPathEditFieldLabel  matlab.ui.control.Label
        CalibrationSetPathEditField     matlab.ui.control.EditField
        HOGTrainSetPathEditFieldLabel   matlab.ui.control.Label
        HOGTrainSetPathEditField        matlab.ui.control.EditField
        TestSetPathEditFieldLabel       matlab.ui.control.Label
        TestSetPathEditField            matlab.ui.control.EditField
        ResultTree                      matlab.ui.container.Tree
        DataUITable                     matlab.ui.control.Table
        StatsUITable                    matlab.ui.control.Table
        FinalUITable                    matlab.ui.control.Table
        RimparametersLabel              matlab.ui.control.Label
        StatisticsLabel                 matlab.ui.control.Label
        FinalLabel                      matlab.ui.control.Label
        CalculationtimeEditFieldLabel   matlab.ui.control.Label
        CalculationtimeEditField        matlab.ui.control.NumericEditField
        VisualisePanel                  matlab.ui.container.Panel
        RimButton                       matlab.ui.control.Button
        CentralHoleButton               matlab.ui.control.Button
        ScrewsButton                    matlab.ui.control.Button
        VentilButton                    matlab.ui.control.Button
        CalibrationSquareSizeEditFieldLabel  matlab.ui.control.Label
        CalibrationSquareSizeEditField  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        visualiseData % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            calibrationSetPath = convertCharsToStrings(app.CalibrationSetPathEditField.Value);
            squareSize = app.CalibrationSquareSizeEditField.Value;
            hogTrainSetPath = convertCharsToStrings(app.HOGTrainSetPathEditField.Value);
            testSetPath = convertCharsToStrings(app.TestSetPathEditField.Value);
            maxNoTranslations = app.MaxNOTranslationsEditField.Value;
            result = calculateRimsForMainView(calibrationSetPath, squareSize, hogTrainSetPath, testSetPath, maxNoTranslations);
            
            app.ResultTree.Children.delete;
            for i = 1 : length(result)
                n1 = uitreenode(app.ResultTree, 'Text', result(i).path, 'Tag', 'path');
                for j = 1 : length(result(i).tac)
                    n2 = uitreenode(n1, 'Text', (j - 1) + " translations", 'NodeData', result(i).tac(j), 'Tag', 'data');
                    for k = ["min", "max", "mean", "median", "mode", "var", "std"]
                        uitreenode(n2, 'Text', k + " stats", 'NodeData', result(i).tac(j), 'Tag', 'stat');
                    end
                end
            end
        end

        % Selection changed function: ResultTree
        function ResultTreeSelectionChanged(app, event)
            selectedNodes = app.ResultTree.SelectedNodes;
            if ~isempty(selectedNodes.NodeData)
                dataNode = selectedNodes;
                while ~strcmp(dataNode.Tag, 'data')
                    dataNode = dataNode.Parent;
                end
                dataTableUserData = selectedNodes.NodeData;
                dataTableUserData.noTranslations = str2double(erase(dataNode.Text, " translations"));
                dataTableUserData.imgPath = dataNode.Parent.Text;
                set(app.DataUITable, 'Data', struct2table(selectedNodes.NodeData.data, 'AsArray', 1), 'UserData', dataTableUserData);
                for i=1 : length(selectedNodes.NodeData.rec)
                    recs(i, 1) = selectedNodes.NodeData.rec(i);
                    recs(i, 2) = selectedNodes.NodeData.correctRec(i);
                end
                set(app.FinalUITable, 'Data', recs)
                set(app.CalculationtimeEditField, 'Value', selectedNodes.NodeData.tim);
                if strcmp(selectedNodes.Tag, 'stat')
                    f = erase(selectedNodes.Text,' stats');
                    set(app.StatsUITable, 'Data', struct2table(selectedNodes.NodeData.stats.(f), 'AsArray', 0));
                else
                    set(app.StatsUITable, 'Data', []);
                end
                set(app.RimButton, 'Enable', false)
                set(app.CentralHoleButton, 'Enable', false)
                set(app.ScrewsButton, 'Enable', false)
                set(app.VentilButton, 'Enable', false)
            end
        end

        % Cell selection callback: DataUITable
        function DataUITableCellSelection(app, event)
            app.visualiseData = event.Source.UserData.data(event.Indices(1));
            app.visualiseData.noTranslations = event.Source.UserData.noTranslations;
            app.visualiseData.noRecord = event.Indices(1);
            app.visualiseData.imgPath = event.Source.UserData.imgPath;
            set(app.RimButton, 'Enable', true)
            set(app.CentralHoleButton, 'Enable', true)
            set(app.ScrewsButton, 'Enable', true)
            set(app.VentilButton, 'Enable', true)
        end

        % Button pushed function: CentralHoleButton, RimButton, 
        % ScrewsButton, VentilButton
        function VisualiseButtonPushed(app, event)
            img = imread(app.TestSetPathEditField.Value + "/" + app.visualiseData.imgPath);
            
            rXY = app.visualiseData.rXY;
            rR = app.visualiseData.rD / 2;
            
            if (app.visualiseData.noTranslations > 0)
                [xSiz, ySiz] = size(img);
                xShift = (xSiz / 2 - rR) / app.visualiseData.noTranslations;
                yShift = (ySiz / 2 - rR) / app.visualiseData.noTranslations;
                i = -app.visualiseData.noTranslations;
                j = -app.visualiseData.noTranslations;
                k = app.visualiseData.noRecord;
                while k > 1
                    j = j + 1;
                    if j > app.visualiseData.noTranslations
                        i = i + 1;
                        j = - app.visualiseData.noTranslations;
                    end
                    k = k - 1;
                end
                img = imtranslate(img, [i * xShift, j * yShift]);
            end
            
            figure('visible','off');
            roi = drawcircle('Center', rXY, 'Radius', rR);
            mask = createMask(roi, img);
            img1 = img;
            img1(mask == 0) = 0;
            
            figure('visible','off');
            switch event.Source.Text
                case 'Rim'
                    xy = rXY;
                    r = rR;
                case 'Central Hole'
                    xy = app.visualiseData.chXY;
                    r = app.visualiseData.chD / 2;
                    roi = drawcircle('Center', rXY, 'Radius', rR / 2);
                    mask = createMask(roi, img1);
                    img1(mask == 0) = 0;
                case 'Screws'
                    xy = app.visualiseData.sXY;
                    r = app.visualiseData.sD / 2;
                    chR = app.visualiseData.chD / 2;
                    roi = drawcircle('Center', rXY, 'Radius', rR / 2);
                    mask = createMask(roi, img1);
                    img1(mask == 0) = 0;
                    roi = drawcircle('Center', rXY, 'Radius', chR);
                    mask = createMask(roi, img1);
                    img1(mask == 1) = 0;
                case 'Ventil'
                    xy = app.visualiseData.vXY;
                    r = app.visualiseData.vD / 2;
                    roi = drawcircle('Center', rXY, 'Radius', 3 * rR / 4);
                    mask = createMask(roi, img1);
                    img1(mask == 1) = 0;
            end
            
            close all
            
            subplot(2,2,1);
            imshow(img);
            subplot(2,2,2);
            imshow(mask);
            subplot(2,2,3);
            imshow(img1);
            subplot(2,2,4);
            imshow(img1);
            hold on
            for i = 1 : length(r)
                plot([xy(i, 1), xy(i, 1) + r(i)], [xy(i, 2), xy(i, 2)], 'g', 'LineWidth', 2); 
                viscircles(xy, r, 'Color', 'b');
                plot(xy(i, 1), xy(i, 2), 'r.', 'MarkerSize', 15);
            end
            hold off
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MaxNOTranslationsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxNOTranslationsEditFieldLabel.Text = 'Max NO Translations';
            app.MaxNOTranslationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalibrationSetPathEditFieldLabel = uilabel(app.UIFigure);
            app.CalibrationSetPathEditFieldLabel.Text = 'Calibration Set Path';
            app.CalibrationSetPathEditField = uieditfield(app.UIFigure, 'text');
            app.CalibrationSetPathEditField.Value = 'Cal';
            app.HOGTrainSetPathEditFieldLabel = uilabel(app.UIFigure);
            app.HOGTrainSetPathEditFieldLabel.Text = 'HOG Train Set Path';
            app.HOGTrainSetPathEditField = uieditfield(app.UIFigure, 'text');
            app.HOGTrainSetPathEditField.Value = 'Hog';
            app.TestSetPathEditFieldLabel = uilabel(app.UIFigure);
            app.TestSetPathEditFieldLabel.Text = 'Test Set Path';
            app.TestSetPathEditField = uieditfield(app.UIFigure, 'text');
            app.TestSetPathEditField.Value = 'Rims';
            app.ResultTree = uitree(app.UIFigure);
            app.ResultTree.SelectionChangedFcn = createCallbackFcn(app, @ResultTreeSelectionChanged, true);
            app.DataUITable = uitable(app.UIFigure);
            app.DataUITable.ColumnName = {'rXY'; 'rD'; 'chXY'; 'chD'; 'chC'; 'sXY'; 'sD'; 'sQ'; 'pcD'; 'pcC'; 'vXY'; 'vD'; 'vA'; 'tim'; 'rDW'; 'chDW'; 'sDW'; 'vDW'; 'sCDW'};
            app.DataUITable.RowName = {};
            app.DataUITable.CellSelectionCallback = createCallbackFcn(app, @DataUITableCellSelection, true);
            app.StatsUITable = uitable(app.UIFigure);
            app.StatsUITable.ColumnName = {'rD'; 'chD'; 'chC'; 'sD'; 'sQ'; 'pcD'; 'pcC'; 'vD'; 'vA'; 'tim'};
            app.StatsUITable.RowName = {''};
            app.FinalUITable = uitable(app.UIFigure);
            app.FinalUITable.ColumnName = {'Recognized rim'; 'Correct rim'};
            app.FinalUITable.RowName = {};
            app.RimparametersLabel = uilabel(app.UIFigure);
            app.RimparametersLabel.Text = 'Rim parameters';
            app.StatisticsLabel = uilabel(app.UIFigure);
            app.StatisticsLabel.Text = 'Statistics';
            app.FinalLabel = uilabel(app.UIFigure);
            app.FinalLabel.Text = 'Final';
            app.CalculationtimeEditFieldLabel = uilabel(app.UIFigure);
            app.CalculationtimeEditFieldLabel.Text = 'Calculation time';
            app.CalculationtimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculationtimeEditField.Editable = 'off';
            app.VisualisePanel = uipanel(app.UIFigure);
            app.VisualisePanel.Title = 'Visualise';
            app.RimButton = uibutton(app.VisualisePanel, 'push');
            app.RimButton.ButtonPushedFcn = createCallbackFcn(app, @VisualiseButtonPushed, true);
            app.RimButton.Enable = 'off';
            app.RimButton.Text = 'Rim';
            app.CentralHoleButton = uibutton(app.VisualisePanel, 'push');
            app.CentralHoleButton.ButtonPushedFcn = createCallbackFcn(app, @VisualiseButtonPushed, true);
            app.CentralHoleButton.Enable = 'off';
            app.CentralHoleButton.Text = 'Central Hole';
            app.ScrewsButton = uibutton(app.VisualisePanel, 'push');
            app.ScrewsButton.ButtonPushedFcn = createCallbackFcn(app, @VisualiseButtonPushed, true);
            app.ScrewsButton.Enable = 'off';
            app.ScrewsButton.Text = 'Screws';
            app.VentilButton = uibutton(app.VisualisePanel, 'push');
            app.VentilButton.ButtonPushedFcn = createCallbackFcn(app, @VisualiseButtonPushed, true);
            app.VentilButton.Enable = 'off';
            app.VentilButton.Text = 'Ventil';
            app.CalibrationSquareSizeEditFieldLabel = uilabel(app.UIFigure);
            app.CalibrationSquareSizeEditFieldLabel.Text = 'Calibration Square Size';
            app.CalibrationSquareSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalibrationSquareSizeEditField.Value = 25;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end