classdef ProcessRimPhoto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProcessRimPhotoUIFigure         matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        FileSaveResultMenu              matlab.ui.container.Menu
        FileExitMenu                    matlab.ui.container.Menu
        GenerateMenu                    matlab.ui.container.Menu
        GenerateCameraCalibrationModelMenu  matlab.ui.container.Menu
        GenerateHOGClassifierMenu       matlab.ui.container.Menu
        GenerateParamsClassifierMenu    matlab.ui.container.Menu
        MainGridLayout                  matlab.ui.container.GridLayout
        InputPanel                      matlab.ui.container.Panel
        InputGridLayout                 matlab.ui.container.GridLayout
        CameraCalibrationPanel          matlab.ui.container.Panel
        CameraCalibrationGridLayout     matlab.ui.container.GridLayout
        CameraCalibrationModelPathLabel  matlab.ui.control.Label
        CameraCalibrationModelPathGridLayout  matlab.ui.container.GridLayout
        CameraCalibrationModelPathEditField  matlab.ui.control.EditField
        CameraCalibrationModelPathButton  matlab.ui.control.Button
        ClassificatorsPanel             matlab.ui.container.Panel
        ClassificatorsGridLayout        matlab.ui.container.GridLayout
        ClassificatorsHOGLabel          matlab.ui.control.Label
        ClassificatorsParamsLabel       matlab.ui.control.Label
        ClassificatorsHOGGridLayout     matlab.ui.container.GridLayout
        ClassificatorsHOGButton         matlab.ui.control.Button
        ClassificatorsHOGEditField      matlab.ui.control.EditField
        ClassificatorsParamsGridLayout  matlab.ui.container.GridLayout
        ClassificatorsParamsEditField   matlab.ui.control.EditField
        ClassificatorsParamsButton      matlab.ui.control.Button
        ImagePanel                      matlab.ui.container.Panel
        ImageGridLayout                 matlab.ui.container.GridLayout
        ImagePathLabel                  matlab.ui.control.Label
        ImagePathGridLayout             matlab.ui.container.GridLayout
        ImagePathEditField              matlab.ui.control.EditField
        ImagePathButton                 matlab.ui.control.Button
        ProcessPanel                    matlab.ui.container.Panel
        ProcessGridLayout               matlab.ui.container.GridLayout
        ProcessButton                   matlab.ui.control.Button
        OutputPanel                     matlab.ui.container.Panel
        OutputGridLayout                matlab.ui.container.GridLayout
        OutputTabGroup                  matlab.ui.container.TabGroup
        OutputResultTab                 matlab.ui.container.Tab
        OutputResultGridLayout          matlab.ui.container.GridLayout
        CircleParametesUITable          matlab.ui.control.Table
        OtherResultsGridLayout          matlab.ui.container.GridLayout
        CentralHoleCentricityLabel      matlab.ui.control.Label
        CentralHoleCentricityEditField  matlab.ui.control.EditField
        PitchCIrcleDiameterLabel        matlab.ui.control.Label
        PitchCIrcleDiameterEditField    matlab.ui.control.NumericEditField
        PitchCircleCentricityLabel      matlab.ui.control.Label
        PitchCircleCentricityEditField  matlab.ui.control.EditField
        VentilAngleLabel                matlab.ui.control.Label
        VentilAngleEditField            matlab.ui.control.NumericEditField
        ImageProcessTimeLabel           matlab.ui.control.Label
        ProcessTimeEditField            matlab.ui.control.NumericEditField
        HOGRecognitionLabel             matlab.ui.control.Label
        HOGRecognitionEditField         matlab.ui.control.EditField
        ParamsRecognitionLabel          matlab.ui.control.Label
        ParamsRecognitionEditField      matlab.ui.control.EditField
        HOGRecognitionTimeLabel         matlab.ui.control.Label
        HOGRecognitionTimeEditField     matlab.ui.control.NumericEditField
        ParamsRecognitionTimeLabel      matlab.ui.control.Label
        ParamsRecognitionTimeEditField  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        cameraParams
        cameraRotation
        cameraTranslation
        cameraSquareSize
        classificatorHOG
        classificatorParams
        image
        result
    end
    
    methods (Access = private)
        
        function filePathButtonPressed(app, event, field, title)
            [file, path] = uigetfile('*', title);
            
            if ~isequal(file, 0)
                field.Value = fullfile(path, file);
                field.ValueChangedFcn(app, event);
            end
        end
        
        function imageClicked(app, event)
            data = event.Source.UserData;
            figure('Name', data.title)
            imshow(data.img)
            if all(isfield(data, {'xy','r'}))
                hold on
                for i = 1 : length(data.r)
                    plot([data.xy(i, 1), data.xy(i, 1) + data.r(i)], [data.xy(i, 2), data.xy(i, 2)], 'g', 'LineWidth', 2); 
                    viscircles(data.xy, data.r, 'Color', 'b');
                    plot(data.xy(i, 1), data.xy(i, 2), 'r.', 'MarkerSize', 15);
                end
                hold off
            end
        end
        
        function checkIfReadyToProcess(app)
            if all(~cellfun(@isempty, {app.cameraParams, app.cameraRotation, app.cameraTranslation, ...
                    app.cameraSquareSize, app.classificatorHOG, app.classificatorParams, app.image}))
                app.ProcessButton.Enable = true;
            else
                app.ProcessButton.Enable = false;
            end
        end
        
        function generateRawImageGraphicTab(app)
            userData.title = 'Raw Picture';
            userData.img = app.image;
            
            tab = uitab(app.OutputTabGroup, 'Title', userData.title);
            grid = uigridlayout(tab, [1 1], 'Padding', [2, 2, 2, 2]);
            imshow(userData.img)
            uiimage(grid, 'ImageSource', frame2im(getframe(gca)), 'UserData', userData, 'ImageClickedFcn', createCallbackFcn(app, @imageClicked, true));
        end
        
        function generateGraphicTabs(app, parameter, data)
            rXY = data.rXY;
            rR = data.rD / 2;
            
            img = app.image;
            roi = drawcircle('Center', rXY, 'Radius', rR);
            mask = createMask(roi, img);
            img1 = img;
            img1(mask == 0) = 0;
        
            switch parameter
                case 'r'
                    xy = rXY;
                    r = rR;
                    tab = uitab(app.OutputTabGroup, 'Title', 'Rim');
                    mask1 = mask;
                case 'ch'
                    xy = data.chXY;
                    r = data.chD / 2;
                    roi = drawcircle('Center', rXY, 'Radius', rR / 2);
                    mask = createMask(roi, img1);
                    img1(mask == 0) = 0;
                    tab = uitab(app.OutputTabGroup, 'Title', 'Central Hole');
                    mask1 = mask;
                case 's'
                    xy = data.sXY;
                    r = data.sD / 2;
                    chR = data.chD / 2;
                    roi = drawcircle('Center', rXY, 'Radius', rR / 2);
                    mask = createMask(roi, img1);
                    img1(mask == 0) = 0;
                    roi = drawcircle('Center', rXY, 'Radius', chR);
                    mask1 = mask;
                    mask = createMask(roi, img1);
                    img1(mask == 1) = 0;
                    tab = uitab(app.OutputTabGroup, 'Title', 'Screws');
                    mask1(mask == 1) = 0;
                case 'v'
                    mask1 = mask;
                    xy = data.vXY;
                    r = data.vD / 2;
                    roi = drawcircle('Center', rXY, 'Radius', 3 * rR / 4);
                    mask = createMask(roi, img1);
                    img1(mask == 1) = 0;
                    tab = uitab(app.OutputTabGroup, 'Title', 'Ventil');
                    mask1(mask == 1) = 0;
            end
            
            grid = uigridlayout(tab, [1 1], 'Padding', [2, 2, 2, 2]);
            tabGr = uitabgroup(grid);
            
            imshow(img1);
            hold on
            for i = 1 : length(r)
                plot([xy(i, 1), xy(i, 1) + r(i)], [xy(i, 2), xy(i, 2)], 'g', 'LineWidth', 2); 
                viscircles(xy, r, 'Color', 'b');
                plot(xy(i, 1), xy(i, 2), 'r.', 'MarkerSize', 15);
            end
            hold off
            tab1 = uitab(tabGr, 'Title', 'Detected Parameters');
            grid1 = uigridlayout(tab1, [1 1], 'Padding', [2, 2, 2, 2]);
            userData.title = tab.Title + " - Detected Parameters";
            userData.img = img1;
            userData.xy = xy;
            userData.r = r;
            uiimage(grid1, 'ImageSource', frame2im(getframe(gca)), 'UserData', userData, 'ImageClickedFcn', createCallbackFcn(app, @imageClicked, true));
            
            tab1 = uitab(tabGr, 'Title', 'Mask');
            grid1 = uigridlayout(tab1, [1 1], 'Padding', [2, 2, 2, 2]);
            imshow(mask1);
            userData1.title = tab.Title + " - Mask";
            userData1.img = mask1;
            uiimage(grid1, 'ImageSource', frame2im(getframe(gca)), 'UserData', userData1, 'ImageClickedFcn', createCallbackFcn(app, @imageClicked, true));
            
            tab1 = uitab(tabGr, 'Title', 'Region of Interest');
            grid1 = uigridlayout(tab1, [1 1], 'Padding', [2, 2, 2, 2]);
            imshow(img1);
            userData2.title = tab.Title + " - Region of Interest";
            userData2.img = img1;
            uiimage(grid1, 'ImageSource', frame2im(getframe(gca)), 'UserData', userData2, 'ImageClickedFcn', createCallbackFcn(app, @imageClicked, true));
        end
        
        function result = calculateRim(app)
            addpath('Scripts')
        
            warning('off', 'images:imfindcircles:warnForLargeRadiusRange');
            warning('off', 'images:imfindcircles:warnForSmallRadius');
            
            [~, ~, colCh] = size(app.image);
            if colCh > 1
                app.img = rgb2gray(app.image);
            end
            
            result.data = calculateRimParameters(app.image);
            [result.hogRec, result.hogTim] = recognizeRimByHOG(app.classificatorHOG, app.image, 'rimXY', result.data.rXY, 'rimRadius', result.data.rD / 2);
            [result.paramsRec, result.paramsTim] = recognizeRimByParams(app.classificatorParams, result.data);
            
            result.data.rDW = calculateWorldDistance(app.cameraParams, app.cameraRotation, app.cameraTranslation, result.data.rXY, [result.data.rXY(1) + result.data.rD, result.data.rXY(2)]);
            result.data.chDW = calculateWorldDistance(app.cameraParams, app.cameraRotation, app.cameraTranslation, result.data.chXY, [result.data.chXY(1) + result.data.chD, result.data.chXY(2)]);
            for l = 1 : result.data.sQ
                result.data.sDW(l) = calculateWorldDistance(app.cameraParams, app.cameraRotation, app.cameraTranslation, result.data.sXY(l,:), [result.data.sXY(l,1) + result.data.sD(l), result.data.sXY(l,2)]);
            end
            result.data.vDW = calculateWorldDistance(app.cameraParams, app.cameraRotation, app.cameraTranslation, result.data.vXY, [result.data.vXY(1) + result.data.vD, result.data.vXY(2)]);
            result.data.pcDW = calculateWorldDistance(app.cameraParams, app.cameraRotation, app.cameraTranslation, [0 0], [result.data.pcD, 0]);
            
            sQ = result.data.sQ;
            Parameter = ["Rim"; "Central Hole"];
            X(:, 1) = [result.data.rXY(1), result.data.chXY(1)];
            Y(:, 1) = [result.data.rXY(2), result.data.chXY(2)];
            D(:, 1) = [result.data.rD, result.data.chD];
            DW(:, 1) = [result.data.rDW, result.data.chDW];
            for i = 1 : sQ
                Parameter(i + 2) = "Screw " + i;
                X(i + 2, 1) = result.data.sXY(i, 1);
                Y(i + 2, 1) = result.data.sXY(i, 2);
                D(i + 2, 1) = result.data.sD(i);
                DW(i + 2, 1) = result.data.sDW(i);
            end
            Parameter(sQ + 3) = "Ventil";
            X(sQ + 3, 1) = result.data.vXY(1);
            Y(sQ + 3, 1) = result.data.vXY(2);
            D(sQ + 3, 1) = result.data.vD;
            DW(sQ + 3, 1) = result.data.vDW;
            
            app.CircleParametesUITable.Data = table(Parameter, X, Y, D, DW);
            app.CentralHoleCentricityEditField.Value = num2str(result.data.chC);
            app.PitchCIrcleDiameterEditField.Value = result.data.pcD;
            app.PitchCircleCentricityEditField.Value = num2str(result.data.pcC);
            app.VentilAngleEditField.Value = result.data.vA;
            app.ProcessTimeEditField.Value = result.data.tim;
            app.HOGRecognitionEditField.Value = string(result.hogRec);
            app.HOGRecognitionTimeEditField.Value = result.hogTim;
            app.ParamsRecognitionEditField.Value = string(result.paramsRec);
            app.ParamsRecognitionTimeEditField.Value = result.paramsTim;
            
            if ~result.data.sDC
                warndlg('Screws was not detected correctly!', 'Warning')
            end
            
            while length(app.OutputTabGroup.Children) > 1
                app.OutputTabGroup.Children(2).Parent = [];
            end
            
            figure('visible','off');
            
            generateRawImageGraphicTab(app);
            for p = ["r", "ch", "s", "v"]
                generateGraphicTabs(app, p, result.data);
            end
            
            close all;
            
            app.result = result;
            app.FileSaveResultMenu.Enable = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: FileExitMenu
        function FileExitMenuSelected(app, event)
            app.delete;
        end

        % Menu selected function: GenerateCameraCalibrationModelMenu
        function GenerateCameraCalibrationModelMenuSelected(app, event)
            function pathButtonPushed(pathEditField)
                path = uigetdir(pwd, 'Choose Camera Calibration Images Directory');
                if ~isequal(path, 0)
                    pathEditField.Value = path;
                end
            end
            
            function okButtonPushed(pathEditField, squareSizeEditField)
                squareSize = squareSizeEditField.Value;
                [cameraParams, rotation, translation, tim] = calibrateCamera(convertCharsToStrings(pathEditField.Value), squareSize);
                msgbox("Camera calibrated within " + tim + " s", "Success")
                
                app.cameraParams = cameraParams;
                app.cameraRotation = rotation;
                app.cameraTranslation = translation;
                app.cameraSquareSize = squareSize;
                
                [file, path] = uiputfile('cameraCalibrationModel.mat', 'Choose HOG Classifier Model Save Place');
                if ~isequal(file, 0)
                    fullPath = fullfile(path, file);
                    save(fullPath, 'cameraParams', 'rotation', 'translation', 'squareSize');
                    app.CameraCalibrationModelPathEditField.Value = fullPath;
                    msgbox("Camera calibrated model saved succesfully", "Success")
                end
            end
            
            function cancelButtonPushed(fig)
                fig.delete;
            end
            
            figWidth = 400;
            figHeight = 200;
            position = [app.ProcessRimPhotoUIFigure.Position(3) / 2 - figWidth, app.ProcessRimPhotoUIFigure.Position(4) / 2 - figHeight, figWidth, figHeight];
            
            fig = uifigure('Name', 'Generate Camera Calibration Model', 'Position', position);
            grid = uigridlayout(fig, [6 1], 'RowHeight', {34, 34, 34, 34, 34, '1x'}, 'RowSpacing', 2, 'Padding', [2, 2, 2, 2]);
            
            uilabel(grid, 'Text', 'Calibration images directory path', 'HorizontalAlignment', 'center');
            grid1 = uigridlayout(grid, [1 2], 'ColumnWidth', {'4x', '1x'}, 'ColumnSpacing', 2, 'Padding', [2, 2, 2, 2]);
            pathEditField = uieditfield(grid1, 'text');
            uibutton(grid1, 'push', 'Text' ,'📁', 'ButtonPushedFcn', @(app, event) pathButtonPushed(pathEditField));
            
            uilabel(grid, 'Text', 'Calibration sqare size (mm)', 'HorizontalAlignment', 'center');
            squareSizeEditField = uieditfield(grid, 'numeric');
            
            grid2 = uigridlayout(grid, [1 2], 'ColumnSpacing', 2, 'Padding', [2, 2, 2, 2]);
            uibutton(grid2, 'push', 'Text', 'Ok', 'ButtonPushedFcn', @(app, event) okButtonPushed(pathEditField, squareSizeEditField));
            uibutton(grid2, 'push', 'Text', 'Cancel', 'ButtonPushedFcn', @(app, event) cancelButtonPushed(fig));
        end

        % Menu selected function: GenerateHOGClassifierMenu
        function GenerateHOGClassifierMenuSelected(app, event)
            path = uigetdir(pwd, 'Choose HOG Classifier Calibration Images Directory');
            if ~isequal(path, 0)
                [hogClassifier, tim] = buildHogClassifier(convertCharsToStrings(path));
                msgbox("HOG classifier builded within " + tim + " s", "Success")
                
                app.classificatorHOG = hogClassifier;
                
                [file, path] = uiputfile('hogClassifier.mat', 'Choose HOG Classifier Model Save Place');
                if ~isequal(file, 0)
                    fullPath = fullfile(path, file);
                    save(fullPath, 'hogClassifier');
                    app.ClassificatorsHOGEditField.Value = fullPath;
                    msgbox("HOG classifier model saved succesfully", "Success")
                end
            end
        end

        % Menu selected function: GenerateParamsClassifierMenu
        function GenerateParamsClassifierMenuSelected(app, event)
            path = uigetdir(pwd, 'Choose Params Classifier Calibration Images Directory');
            if ~isequal(path, 0)
                [paramsClassifier, tim] = buildParamsClassifier(convertCharsToStrings(path));
                msgbox("Params classifier builded within " + tim + " s", "Success")
                
                app.classificatorParams = paramsClassifier;
                
                [file, path] = uiputfile('paramsClassifier.mat', 'Choose Params Classifier Model Save Place');
                if ~isequal(file, 0)
                    fullPath = fullfile(path, file);
                    save(fullPath, 'paramsClassifier');
                    app.ClassificatorsParamsEditField.Value = fullPath;
                    msgbox("Params classifier model saved succesfully", "Success")
                end
            end
        end

        % Value changed function: 
        % CameraCalibrationModelPathEditField
        function CameraCalibrationModelPathEditFieldValueChanged(app, event)
            value = app.CameraCalibrationModelPathEditField.Value;
            
            if any(isfile({value, strcat(value, '.mat')}))
                data = load(value);
                
                if ~isempty(data) && all(isfield(data, {'cameraParams', 'rotation', 'translation', 'squareSize'}))
                    app.cameraParams = data.cameraParams;
                    app.cameraRotation = data.rotation;
                    app.cameraTranslation = data.translation;
                    app.cameraSquareSize = data.squareSize;
                    
                    checkIfReadyToProcess(app)
                    return
                end
            end
            
            app.cameraParams = [];
            app.cameraRotation = [];
            app.cameraTranslation = [];
            app.cameraSquareSize = [];
            app.ProcessButton.Enable = false;
        end

        % Button pushed function: CameraCalibrationModelPathButton
        function CameraCalibrationModelPathButtonPushed(app, event)
            filePathButtonPressed(app, event, app.CameraCalibrationModelPathEditField, 'Choose Camera Calibration Model');
        end

        % Value changed function: ClassificatorsHOGEditField
        function ClassificatorsHOGEditFieldValueChanged(app, event)
            value = app.ClassificatorsHOGEditField.Value;
            
            if any(isfile({value, strcat(value, '.mat')}))
                data = load(value);
                
                if ~isempty(data) && isfield(data, 'hogClassifier')
                    app.classificatorHOG = data.hogClassifier;
                    
                    checkIfReadyToProcess(app)
                    return
                end
            end
            
            app.classificatorHOG = [];
            app.ProcessButton.Enable = false;
        end

        % Button pushed function: ClassificatorsHOGButton
        function ClassificatorsHOGButtonPushed(app, event)
            filePathButtonPressed(app, event, app.ClassificatorsHOGEditField, 'Choose HOG Classificator Model');
        end

        % Value changed function: ClassificatorsParamsEditField
        function ClassificatorsParamsEditFieldValueChanged(app, event)
            value = app.ClassificatorsParamsEditField.Value;
            
            if any(isfile({value, strcat(value, '.mat')}))
                data = load(value);
                
                if ~isempty(data) && isfield(data, 'paramsClassifier')
                    app.classificatorParams = data.paramsClassifier;
                    
                    checkIfReadyToProcess(app)
                    return
                end
            end
            
            app.classificatorParams = [];
            app.ProcessButton.Enable = false;
        end

        % Button pushed function: ClassificatorsParamsButton
        function ClassificatorsParamsButtonPushed(app, event)
            filePathButtonPressed(app, event, app.ClassificatorsParamsEditField, 'Choose Params Classificator Model');
        end

        % Value changed function: ImagePathEditField
        function ImagePathEditFieldValueChanged(app, event)
            value = app.ImagePathEditField.Value;
            
            if isfile(value)
                app.image = imread(value);
                
                checkIfReadyToProcess(app)
                return
            end
            
            app.image = [];
            app.ProcessButton.Enable = false;
        end

        % Button pushed function: ImagePathButton
        function ImagePathButtonPushed(app, event)
            filePathButtonPressed(app, event, app.ImagePathEditField, 'Choose Rim Image');
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            calculateRim(app);
        end

        % Menu selected function: FileSaveResultMenu
        function FileSaveResultMenuSelected(app, event)
            [file, path] = uiputfile('rimParams.txt', 'Choose Rim Params Save Place');
            if ~isequal(file, 0)
                fullPath = fullfile(path, file);
                f = fopen(fullPath, 'w+');
                
                fprintf(f, "Rim:\n");
                fprintf(f, "  xy: [%f %f]\n", app.result.data.rXY(1), app.result.data.rXY(2));
                fprintf(f, "  diameter: %f px = %f mm\n", app.result.data.rD, app.result.data.rDW);
                fprintf(f, "Central hole:\n");
                fprintf(f, "  xy: [%f %f]\n", app.result.data.chXY(1), app.result.data.chXY(2));
                fprintf(f, "  diameter: %f px = %f mm\n", app.result.data.chD, app.result.data.chDW);
                fprintf(f, "  centricity vector: [%f %f]\n", app.result.data.chC(1), + app.result.data.chC(2));
                fprintf(f, "Screw holes:\n");
                fprintf(f, "  quantity: %d\n", app.result.data.sQ);
                fprintf(f, "  xy:\n");
                for i = 1 : app.result.data.sQ
                    fprintf(f, "    [%f %f]\n", app.result.data.sXY(1), app.result.data.sXY(2));
                end
                fprintf(f, "  diameters:\n");
                for i = 1 : app.result.data.sQ
                    fprintf(f, "    %f px = %f mm\n", app.result.data.sD(i), app.result.data.sDW(i));
                end
                if ~app.result.data.sDC
                    fprintf(f, "    Screws was not detected correctly!\n");
                end
                fprintf(f, "Pitch circle:\n");
                fprintf(f, "  diameter: %f px\n", app.result.data.pcD);
                fprintf(f, "  centricity vector: [%f %f]\n", app.result.data.pcC(1), app.result.data.pcC(2));
                fprintf(f, "Ventil hole:\n");
                fprintf(f, "  xy: [%f %f]\n", app.result.data.vXY(1), app.result.data.vXY(2));
                fprintf(f, "  diameter: %f px = %f mm\n", app.result.data.vD, app.result.data.vDW);
                fprintf(f, "  angle: %f deg\n", app.result.data.vA);
                fprintf(f, "Parameters calculation time: %f\n", app.result.data.tim);
                fprintf(f, "HOG recognizion result: %s\n", char(app.result.hogRec));
                fprintf(f, "HOG classification time: %f\n", app.result.hogTim);
                fprintf(f, "Params recognizion result: %s\n", char(app.result.paramsRec));
                fprintf(f, "Params classification time: %f\n", app.result.paramsTim);
                
                fclose(f);
                msgbox("Rim Params saved succesfully", "Success")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProcessRimPhotoUIFigure = uifigure('Visible', 'off');
            app.ProcessRimPhotoUIFigure.Name = 'Process Rim Photo';
            app.FileMenu = uimenu(app.ProcessRimPhotoUIFigure);
            app.FileMenu.Text = 'File';
            app.FileSaveResultMenu = uimenu(app.FileMenu);
            app.FileSaveResultMenu.MenuSelectedFcn = createCallbackFcn(app, @FileSaveResultMenuSelected, true);
            app.FileSaveResultMenu.Enable = 'off';
            app.FileSaveResultMenu.Text = 'Save Result...';
            app.FileExitMenu = uimenu(app.FileMenu);
            app.FileExitMenu.MenuSelectedFcn = createCallbackFcn(app, @FileExitMenuSelected, true);
            app.FileExitMenu.Separator = 'on';
            app.FileExitMenu.Text = 'Exit';
            app.GenerateMenu = uimenu(app.ProcessRimPhotoUIFigure);
            app.GenerateMenu.Text = 'Generate';
            app.GenerateCameraCalibrationModelMenu = uimenu(app.GenerateMenu);
            app.GenerateCameraCalibrationModelMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateCameraCalibrationModelMenuSelected, true);
            app.GenerateCameraCalibrationModelMenu.Text = 'Generate Camera Calibration Model...';
            app.GenerateHOGClassifierMenu = uimenu(app.GenerateMenu);
            app.GenerateHOGClassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateHOGClassifierMenuSelected, true);
            app.GenerateHOGClassifierMenu.Separator = 'on';
            app.GenerateHOGClassifierMenu.Text = 'Generate HOG Classifier...';
            app.GenerateParamsClassifierMenu = uimenu(app.GenerateMenu);
            app.GenerateParamsClassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateParamsClassifierMenuSelected, true);
            app.GenerateParamsClassifierMenu.Text = 'Generate Params Classifier...';
            app.MainGridLayout = uigridlayout(app.ProcessRimPhotoUIFigure);
            app.InputPanel = uipanel(app.MainGridLayout);
            app.InputGridLayout = uigridlayout(app.InputPanel);
            app.CameraCalibrationPanel = uipanel(app.InputGridLayout);
            app.CameraCalibrationPanel.Title = 'Camera Calibration';
            app.CameraCalibrationGridLayout = uigridlayout(app.CameraCalibrationPanel);
            app.CameraCalibrationModelPathLabel = uilabel(app.CameraCalibrationGridLayout);
            app.CameraCalibrationModelPathLabel.Text = 'Model Path';
            app.CameraCalibrationModelPathGridLayout = uigridlayout(app.CameraCalibrationGridLayout);
            app.CameraCalibrationModelPathEditField = uieditfield(app.CameraCalibrationModelPathGridLayout, 'text');
            app.CameraCalibrationModelPathEditField.ValueChangedFcn = createCallbackFcn(app, @CameraCalibrationModelPathEditFieldValueChanged, true);
            app.CameraCalibrationModelPathButton = uibutton(app.CameraCalibrationModelPathGridLayout, 'push');
            app.CameraCalibrationModelPathButton.ButtonPushedFcn = createCallbackFcn(app, @CameraCalibrationModelPathButtonPushed, true);
            app.CameraCalibrationModelPathButton.Text = '📁';
            app.ClassificatorsPanel = uipanel(app.InputGridLayout);
            app.ClassificatorsPanel.Title = 'Classificators';
            app.ClassificatorsGridLayout = uigridlayout(app.ClassificatorsPanel);
            app.ClassificatorsHOGLabel = uilabel(app.ClassificatorsGridLayout);
            app.ClassificatorsHOGLabel.Text = 'HOG Model Path';
            app.ClassificatorsParamsLabel = uilabel(app.ClassificatorsGridLayout);
            app.ClassificatorsParamsLabel.Text = 'Params Model Path';
            app.ClassificatorsHOGGridLayout = uigridlayout(app.ClassificatorsGridLayout);
            app.ClassificatorsHOGButton = uibutton(app.ClassificatorsHOGGridLayout, 'push');
            app.ClassificatorsHOGButton.ButtonPushedFcn = createCallbackFcn(app, @ClassificatorsHOGButtonPushed, true);
            app.ClassificatorsHOGButton.Text = '📁';
            app.ClassificatorsHOGEditField = uieditfield(app.ClassificatorsHOGGridLayout, 'text');
            app.ClassificatorsHOGEditField.ValueChangedFcn = createCallbackFcn(app, @ClassificatorsHOGEditFieldValueChanged, true);
            app.ClassificatorsParamsGridLayout = uigridlayout(app.ClassificatorsGridLayout);
            app.ClassificatorsParamsEditField = uieditfield(app.ClassificatorsParamsGridLayout, 'text');
            app.ClassificatorsParamsEditField.ValueChangedFcn = createCallbackFcn(app, @ClassificatorsParamsEditFieldValueChanged, true);
            app.ClassificatorsParamsButton = uibutton(app.ClassificatorsParamsGridLayout, 'push');
            app.ClassificatorsParamsButton.ButtonPushedFcn = createCallbackFcn(app, @ClassificatorsParamsButtonPushed, true);
            app.ClassificatorsParamsButton.Text = '📁';
            app.ImagePanel = uipanel(app.InputGridLayout);
            app.ImagePanel.Title = 'Image';
            app.ImageGridLayout = uigridlayout(app.ImagePanel);
            app.ImagePathLabel = uilabel(app.ImageGridLayout);
            app.ImagePathLabel.Text = 'Image Path';
            app.ImagePathGridLayout = uigridlayout(app.ImageGridLayout);
            app.ImagePathEditField = uieditfield(app.ImagePathGridLayout, 'text');
            app.ImagePathEditField.ValueChangedFcn = createCallbackFcn(app, @ImagePathEditFieldValueChanged, true);
            app.ImagePathButton = uibutton(app.ImagePathGridLayout, 'push');
            app.ImagePathButton.ButtonPushedFcn = createCallbackFcn(app, @ImagePathButtonPushed, true);
            app.ImagePathButton.Text = '📁';
            app.ProcessPanel = uipanel(app.InputGridLayout);
            app.ProcessPanel.Title = 'Process';
            app.ProcessGridLayout = uigridlayout(app.ProcessPanel);
            app.ProcessButton = uibutton(app.ProcessGridLayout, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Enable = 'off';
            app.ProcessButton.Text = 'Process';
            app.OutputPanel = uipanel(app.MainGridLayout);
            app.OutputGridLayout = uigridlayout(app.OutputPanel);
            app.OutputTabGroup = uitabgroup(app.OutputGridLayout);
            app.OutputResultTab = uitab(app.OutputTabGroup);
            app.OutputResultTab.Title = 'Result';
            app.OutputResultGridLayout = uigridlayout(app.OutputResultTab);
            app.CircleParametesUITable = uitable(app.OutputResultGridLayout);
            app.CircleParametesUITable.ColumnName = {'Parameter'; 'X'; 'Y'; 'D'; 'DW'};
            app.CircleParametesUITable.RowName = {};
            app.OtherResultsGridLayout = uigridlayout(app.OutputResultGridLayout);
            app.CentralHoleCentricityLabel = uilabel(app.OtherResultsGridLayout);
            app.CentralHoleCentricityLabel.Text = 'Central Hole Centricity';
            app.CentralHoleCentricityEditField = uieditfield(app.OtherResultsGridLayout, 'text');
            app.CentralHoleCentricityEditField.Editable = 'off';
            app.PitchCIrcleDiameterLabel = uilabel(app.OtherResultsGridLayout);
            app.PitchCIrcleDiameterLabel.Text = 'Pitch CIrcle Diameter';
            app.PitchCIrcleDiameterEditField = uieditfield(app.OtherResultsGridLayout, 'numeric');
            app.PitchCIrcleDiameterEditField.Editable = 'off';
            app.PitchCircleCentricityLabel = uilabel(app.OtherResultsGridLayout);
            app.PitchCircleCentricityLabel.Text = 'Pitch Circle Centricity';
            app.PitchCircleCentricityEditField = uieditfield(app.OtherResultsGridLayout, 'text');
            app.PitchCircleCentricityEditField.Editable = 'off';
            app.VentilAngleLabel = uilabel(app.OtherResultsGridLayout);
            app.VentilAngleLabel.Text = 'Ventil Angle';
            app.VentilAngleEditField = uieditfield(app.OtherResultsGridLayout, 'numeric');
            app.VentilAngleEditField.Editable = 'off';
            app.ImageProcessTimeLabel = uilabel(app.OtherResultsGridLayout);
            app.ImageProcessTimeLabel.Text = 'Image Process Time';
            app.ProcessTimeEditField = uieditfield(app.OtherResultsGridLayout, 'numeric');
            app.ProcessTimeEditField.Editable = 'off';
            app.HOGRecognitionLabel = uilabel(app.OtherResultsGridLayout);
            app.HOGRecognitionLabel.Text = 'HOG Recognition';
            app.HOGRecognitionEditField = uieditfield(app.OtherResultsGridLayout, 'text');
            app.HOGRecognitionEditField.Editable = 'off';
            app.ParamsRecognitionLabel = uilabel(app.OtherResultsGridLayout);
            app.ParamsRecognitionLabel.Text = 'Params Recognition';
            app.ParamsRecognitionEditField = uieditfield(app.OtherResultsGridLayout, 'text');
            app.ParamsRecognitionEditField.Editable = 'off';
            app.HOGRecognitionTimeLabel = uilabel(app.OtherResultsGridLayout);
            app.HOGRecognitionTimeLabel.Text = 'HOG Recognition TIme';
            app.HOGRecognitionTimeEditField = uieditfield(app.OtherResultsGridLayout, 'numeric');
            app.HOGRecognitionTimeEditField.Editable = 'off';
            app.ParamsRecognitionTimeLabel = uilabel(app.OtherResultsGridLayout);
            app.ParamsRecognitionTimeLabel.Text = 'Params Recognition TIme';
            app.ParamsRecognitionTimeEditField = uieditfield(app.OtherResultsGridLayout, 'numeric');
            app.ParamsRecognitionTimeEditField.Editable = 'off';
            % Show the figure after all components are created
            app.ProcessRimPhotoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProcessRimPhoto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProcessRimPhotoUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProcessRimPhotoUIFigure)
        end
    end
end